classdef simulatorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UITable                        matlab.ui.control.Table
        MatrixALabel                   matlab.ui.control.Label
        MatrixBLabel                   matlab.ui.control.Label
        IEditFieldLabel                matlab.ui.control.Label
        IEditField                     matlab.ui.control.NumericEditField
        RohmLabel                      matlab.ui.control.Label
        RohmEditField                  matlab.ui.control.NumericEditField
        CFEditFieldLabel               matlab.ui.control.Label
        CFEditField                    matlab.ui.control.NumericEditField
        UITable_2                      matlab.ui.control.Table
        UIAxes                         matlab.ui.control.UIAxes
        dtsEditFieldLabel              matlab.ui.control.Label
        dtsEditField                   matlab.ui.control.NumericEditField
        TsEditFieldLabel               matlab.ui.control.Label
        TsEditField                    matlab.ui.control.NumericEditField
        RunButton                      matlab.ui.control.Button
        NameofinputImagefileEditFieldLabel  matlab.ui.control.Label
        NameofinputImagefileEditField  matlab.ui.control.EditField
        showonlyresultCheckBox         matlab.ui.control.CheckBox
        ReadyLampLabel                 matlab.ui.control.Label
        ReadyLamp                      matlab.ui.control.Lamp
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.UITable.Data  = zeros(3,3);
             app.UITable_2.Data  = zeros(3,3);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.ReadyLamp.Color = [1 0 0];
            tabA = get(app.UITable,'data');
            %MatA = table2array(tabA);
            tabB = get(app.UITable_2,'data');
            %MatB = table2array(tabB);
            I = app.IEditField.Value;
            C = app.CFEditField.Value;
            R = app.RohmEditField.Value;
            dt = app.dtsEditField.Value;
            T= app.TsEditField.Value;
            %N = T/dt;
            file = app.NameofinputImagefileEditField.Value;
            RGB = imread(file);
            Imgg = im2double(RGB);
            MatU = Imgg(:,:,1);
            dims = size(MatU);
            m = dims(1);
            n = dims(2);
            Vx = MatU;
            [VxMatHist, VxStable, VyMatHist, VyStable] = simulate(Vx,MatU,m,n,T,C,R,I,dt,tabA,tabB);
            if app.showonlyresultCheckBox.Value == 0
                for i = 1:size(VyMatHist,3)
                    imshow(VyMatHist(:,:,i),'Parent',app.UIAxes)
                    pause(0.000001);
                end 
            else
                imshow(VyStable,'Parent',app.UIAxes);
            end
            app.ReadyLamp.Color = [0 1 0];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'1'; '2'; '3'};
            app.UITable.RowName = {'1'; '2'; '3'};
            app.UITable.ColumnEditable = true;
            app.MatrixALabel = uilabel(app.UIFigure);
            app.MatrixALabel.Text = 'Matrix A';
            app.MatrixBLabel = uilabel(app.UIFigure);
            app.MatrixBLabel.Text = 'Matrix B';
            app.IEditFieldLabel = uilabel(app.UIFigure);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.UIFigure, 'numeric');
            app.IEditField.Value = -1;
            app.RohmLabel = uilabel(app.UIFigure);
            app.RohmLabel.Text = 'R [ohm]';
            app.RohmEditField = uieditfield(app.UIFigure, 'numeric');
            app.RohmEditField.Value = 1000;
            app.CFEditFieldLabel = uilabel(app.UIFigure);
            app.CFEditFieldLabel.Text = 'C [F]';
            app.CFEditField = uieditfield(app.UIFigure, 'numeric');
            app.CFEditField.Value = 1e-09;
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'1'; '2'; '3'};
            app.UITable_2.RowName = {'1'; '2'; '3'};
            app.UITable_2.ColumnEditable = true;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'output')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.dtsEditFieldLabel = uilabel(app.UIFigure);
            app.dtsEditFieldLabel.Text = 'dt [s]';
            app.dtsEditField = uieditfield(app.UIFigure, 'numeric');
            app.dtsEditField.Value = 1e-10;
            app.TsEditFieldLabel = uilabel(app.UIFigure);
            app.TsEditFieldLabel.Text = 'T [s]';
            app.TsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TsEditField.Value = 1.2e-08;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.NameofinputImagefileEditFieldLabel = uilabel(app.UIFigure);
            app.NameofinputImagefileEditFieldLabel.Text = 'Name of input Image file';
            app.NameofinputImagefileEditField = uieditfield(app.UIFigure, 'text');
            app.showonlyresultCheckBox = uicheckbox(app.UIFigure);
            app.showonlyresultCheckBox.Text = 'show only result';
            app.ReadyLampLabel = uilabel(app.UIFigure);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = simulatorApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end